classdef appGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        secondsLabel      matlab.ui.control.Label
        TimeElapsed       matlab.ui.control.Label
        TimeHeader        matlab.ui.control.Label
        FortuneCookie     matlab.ui.control.Image
        OrderImage1       matlab.ui.control.Image
        UnecessaryLabel   matlab.ui.control.Label
        GoTigers          matlab.ui.control.Image
        RemoveItem1       matlab.ui.control.Image
        RemoveItem2       matlab.ui.control.Image
        OrderImage2       matlab.ui.control.Image
        OrderLabel2       matlab.ui.control.Label
        Header            matlab.ui.control.Label
        Image3            matlab.ui.control.Image
        Image2            matlab.ui.control.Image
        Image             matlab.ui.control.Image
        FriesButton       matlab.ui.control.Button
        PlaceOrderButton  matlab.ui.control.Button
        HotDogButton      matlab.ui.control.Button
        BurgerButton      matlab.ui.control.Button
        OrderLabel1       matlab.ui.control.Label
    end

    
    properties (Access = private)
        ItemCount = 0 % Current Items in User Order
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            runtime = evalin('base','telapsed');
            if ~strcmp(runtime, 'none')
                app.TimeElapsed.Text = runtime;
                app.TimeElapsed.Visible = 'on';
                app.TimeHeader.Visible = 'on';
                app.secondsLabel.Visible = 'on';
            end
            
        end

        % Button pushed function: BurgerButton
        function BurgerButtonPushed(app, event)
            if app.ItemCount == 2
                errordlg('You cannot add more than two items to your cart.','Woah There!');
            else
                if app.ItemCount == 0
                    app.OrderLabel1.Text = append(app.OrderLabel1.Text, 'Burger');
                    app.OrderImage1.ImageSource = 'orderburger.png';
                    app.RemoveItem1.Visible = 'on';
                else
                    app.OrderLabel2.Text = append(app.OrderLabel2.Text, 'Burger');
                    app.OrderImage2.ImageSource = 'orderburger.png'; 
                    app.RemoveItem2.Visible = 'on';
                    if contains(app.OrderLabel1.Text,'Burger')
                        app.UnecessaryLabel.Visible = 'on';
                        app.UnecessaryLabel.Text = 'Sheesh! Somebody likes burgers!';
                    end
                end
                app.ItemCount = app.ItemCount + 1;
            end
        end

        % Button pushed function: HotDogButton
        function HotDogButtonPushed(app, event)
            if app.ItemCount == 2
                errordlg('You cannot add more than two items to your cart.','Woah There!');
            else
                if app.ItemCount == 0
                    app.OrderLabel1.Text = append(app.OrderLabel1.Text, 'Hot Dog');
                    app.OrderImage1.ImageSource = 'orderhotdog.png';
                    app.RemoveItem1.Visible = 'on';
                else
                    app.OrderLabel2.Text = append(app.OrderLabel2.Text, 'Hot Dog');
                    app.OrderImage2.ImageSource = 'orderhotdog.png';
                    app.RemoveItem2.Visible = 'on';
                    if contains(app.OrderLabel1.Text,'Hot Dog')
                        app.UnecessaryLabel.Visible = 'on';
                        app.UnecessaryLabel.Text = 'Sheesh! Somebody likes hot dogs!';
                    end
                end
                app.ItemCount = app.ItemCount + 1;
            end
            
        end

        % Button pushed function: FriesButton
        function FriesButtonPushed(app, event)
            if app.ItemCount == 2
                errordlg('You cannot add more than two items to your cart.','Woah There!');
            else
                if app.ItemCount == 0
                    app.OrderLabel1.Text = append(app.OrderLabel1.Text, 'Fries');
                    app.OrderImage1.ImageSource = 'orderfries.png';
                    app.RemoveItem1.Visible = 'on';
                else
                    app.OrderLabel2.Text = append(app.OrderLabel2.Text, 'Fries');  
                    app.OrderImage2.ImageSource = 'orderfries.png';
                    app.RemoveItem2.Visible = 'on';
                    if contains(app.OrderLabel1.Text,'Fries')
                        app.UnecessaryLabel.Visible = 'on';
                        app.UnecessaryLabel.Text = 'Sheesh! Somebody likes fries!';
                    end
                end
                app.ItemCount = app.ItemCount + 1;

            end
            
        end

        % Button pushed function: PlaceOrderButton
        function PlaceOrderButtonPushed(app, event)
            condiments = [];
            if contains(app.OrderLabel1.Text, 'Fries') || contains(app.OrderLabel2.Text, 'Fries')
                condiments = ["Red"];
            end
            if contains(app.OrderLabel1.Text, 'Burger') || contains(app.OrderLabel2.Text, 'Burger')
                condiments = [condiments, "Blue"];
                if ~ismember("Red", condiments)
                   condiments = ["Red", condiments];
                end
                
            end
            if contains(app.OrderLabel1.Text, "Hot Dog") || contains(app.OrderLabel2.Text, 'Hot Dog')
                condiments = [condiments, "Green", "Yellow"];
            end
            assignin('base', 'condiments', condiments);
            assignin('base','orderplaced',1);
            close(app.UIFigure);

        end

        % Image clicked function: RemoveItem2
        function RemoveItem2ImageClicked(app, event)
            app.OrderLabel2.Text = '';
            app.ItemCount = app.ItemCount - 1;
            app.RemoveItem2.Visible = 'off';
            app.OrderImage2.ImageSource = 'orderdefault.png';

            app.UnecessaryLabel.Visible = 'off';
            app.UnecessaryLabel.Text = '';
        end

        % Image clicked function: RemoveItem1
        function RemoveItem1ImageClicked(app, event)
            if app.ItemCount == 1
                app.RemoveItem1.Visible = 'off';
                app.OrderImage1.ImageSource = app.OrderImage2.ImageSource;
            end
            app.OrderImage1.ImageSource = app.OrderImage2.ImageSource;
            app.OrderLabel1.Text = app.OrderLabel2.Text;
            app.RemoveItem2.Visible = 'off';
            app.ItemCount = app.ItemCount - 1;
            app.OrderLabel2.Text = '';
            app.OrderImage2.ImageSource = 'orderdefault.png';
            app.UnecessaryLabel.Visible = 'off';
            app.UnecessaryLabel.Text = '';
        end

        % Image clicked function: FortuneCookie
        function FortuneCookieClicked(app, event)
            fortunes = ["Every new beginning comes from some other beginning's end.",
                "Don't let yesterday take up too much of today.",
                "Little by little, one travels far.",
                "It's only after we've lost everything that we're free to do anything.",
                "You must expect great things of yourself before you can do them.",
                "Not all those who wander are lost.",
                "It does not do to dwell on dreams and forget to live.",
                "Change can hurt, but it leads a path to something better.",
                "Enjoy the good luck a companion brings you.",
                "Meeting adversity well is the source of your strength.",
                "Now is the time to try something new."
                ];
            fortune_no = ceil((rand * length(fortunes)));
            msgbox(fortunes(fortune_no),"Your Fortune.");

        end

        % Image clicked function: GoTigers
        function GoTigersImageClicked(app, event)
           grad_msg = "Graduation isn't the end of a tough journey" + ...
               " It is the beginning of a beautiful one.";
           msgbox(grad_msg, 'Enjoy life!');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OrderLabel1 = uilabel(app.UIFigure);
            app.OrderLabel1.Text = '';
            app.BurgerButton = uibutton(app.UIFigure, 'push');
            app.BurgerButton.ButtonPushedFcn = createCallbackFcn(app, @BurgerButtonPushed, true);
            app.BurgerButton.Text = 'Burger';
            app.HotDogButton = uibutton(app.UIFigure, 'push');
            app.HotDogButton.ButtonPushedFcn = createCallbackFcn(app, @HotDogButtonPushed, true);
            app.HotDogButton.Text = 'Hot Dog';
            app.PlaceOrderButton = uibutton(app.UIFigure, 'push');
            app.PlaceOrderButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceOrderButtonPushed, true);
            app.PlaceOrderButton.Text = 'Place Order';
            app.FriesButton = uibutton(app.UIFigure, 'push');
            app.FriesButton.ButtonPushedFcn = createCallbackFcn(app, @FriesButtonPushed, true);
            app.FriesButton.Text = 'Fries';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DALL·E 2022-12-08 12.32.55 - an oil painting of a hot dog.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'DALL·E 2022-12-08 12.33.25 - an oil painting of french fries.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'DALL·E 2022-12-08 12.33.33 - an oil painting of a cheeseburger.png';
            app.Header = uilabel(app.UIFigure);
            app.Header.Text = 'Please Select Up to Two Items!';
            app.OrderLabel2 = uilabel(app.UIFigure);
            app.OrderLabel2.Text = ' ';
            app.OrderImage2 = uiimage(app.UIFigure);
            app.OrderImage2.ImageSource = 'orderdefault.png';
            app.RemoveItem2 = uiimage(app.UIFigure);
            app.RemoveItem2.ImageClickedFcn = createCallbackFcn(app, @RemoveItem2ImageClicked, true);
            app.RemoveItem2.Visible = 'off';
            app.RemoveItem2.ImageSource = 'redx.png';
            app.RemoveItem1 = uiimage(app.UIFigure);
            app.RemoveItem1.ImageClickedFcn = createCallbackFcn(app, @RemoveItem1ImageClicked, true);
            app.RemoveItem1.Visible = 'off';
            app.RemoveItem1.ImageSource = 'redx.png';
            app.GoTigers = uiimage(app.UIFigure);
            app.GoTigers.ImageClickedFcn = createCallbackFcn(app, @GoTigersImageClicked, true);
            app.GoTigers.ImageSource = 'clemson.png';
            app.UnecessaryLabel = uilabel(app.UIFigure);
            app.UnecessaryLabel.Visible = 'off';
            app.UnecessaryLabel.Text = '';
            app.OrderImage1 = uiimage(app.UIFigure);
            app.OrderImage1.ImageSource = 'orderdefault.png';
            app.FortuneCookie = uiimage(app.UIFigure);
            app.FortuneCookie.ImageClickedFcn = createCallbackFcn(app, @FortuneCookieClicked, true);
            app.FortuneCookie.ImageSource = 'fortunecookie.png';
            app.TimeHeader = uilabel(app.UIFigure);
            app.TimeHeader.Visible = 'off';
            app.TimeHeader.Text = 'Previous Time Elapsed';
            app.TimeElapsed = uilabel(app.UIFigure);
            app.TimeElapsed.Visible = 'off';
            app.TimeElapsed.Text = '';
            app.secondsLabel = uilabel(app.UIFigure);
            app.secondsLabel.Visible = 'off';
            app.secondsLabel.Text = 'seconds';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end